classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        Panel_2                      matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        DropDown                     matlab.ui.control.DropDown
        StartButton                  matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        SavetoCSVButton              matlab.ui.control.Button
        OpenOrientationViewerButton  matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        axesAccel                    matlab.ui.control.UIAxes
        axesGyro                     matlab.ui.control.UIAxes
    end

    properties (Access = private)
        a % Description
        imu % Description
        FUSE % Description
        rotators
        sensorLoop
        accelLog
        gyroLog
        accelX
        accelY
        accelZ
        gyroX
        gyroY
        gyroZ
        myTimer
        viewer
        viewerLoop
        port
    end
    
    methods (Access = private)
        
        function readSensor(app)
            [accel, gyro] = read(app.imu);
            elaspedTime = toc(app.myTimer);
            app.rotators = app.FUSE(accel, gyro);

            % Add to Logs
            newAccel = {elaspedTime, accel(1,1), accel(1,2), accel(1,3)};
            newGyro = {elaspedTime, gyro(1,1), gyro(1,2), gyro(1,3)};
            app.accelLog = [app.accelLog; newAccel];
            app.gyroLog = [app.gyroLog; newGyro];

            % Plotting to Graphs
            if elaspedTime >= app.axesAccel.XLim
                app.axesAccel.XLim = app.axesAccel.XLim + 10;
                app.axesGyro.XLim = app.axesGyro.XLim + 10;
            end
            addpoints(app.accelX, elaspedTime, accel(1,1));
            addpoints(app.accelY, elaspedTime, accel(1,2));
            addpoints(app.accelZ, elaspedTime, accel(1,3));
            legend(app.axesAccel, 'x', 'y', 'z');
            addpoints(app.gyroX, elaspedTime, gyro(1,1));
            addpoints(app.gyroY, elaspedTime, gyro(1,2));
            addpoints(app.gyroZ, elaspedTime, gyro(1,3));
            legend(app.axesGyro, 'x', 'y', 'z');
            drawnow;
        end

        function showViewer(app)
            for j = numel(app.rotators) 
                app.viewer(app.rotators(j)); 
            end 
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            serials = serialportlist("available");
            app.DropDown.Items = serials;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                stop(app.sensorLoop);
                stop(app.viewerLoop);
            catch
            end
            delete(app);            
        end

        % Button pushed function: OpenOrientationViewerButton
        function OpenOrientationViewerButtonPushed(app, event)
            show(app.viewer);
        end

        % Button pushed function: SavetoCSVButton
        function SavetoCSVButtonPushed(app, event)
            currentTime = string(datetime("now"), "dd-MM-yyyy-hh-mm-ss");
            writetable(app.accelLog, sprintf("log/accel/%s.csv", currentTime));
            writetable(app.gyroLog, sprintf("log/gyro/%s.csv", currentTime));
            msgbox("CSV saved","Success");
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.axesAccel);
            cla(app.axesGyro);

            app.axesAccel.XLim = [0, 20];
            app.axesGyro.XLim = [0, 20];
            fs = 100; % Sample Rate in Hz

            % GyroscopeNoise and AccelerometerNoise is determined from datasheet.
            GyroscopeNoiseMPU6050 = 3.0462e-06; % GyroscopeNoise (variance value) in units of rad/s
            AccelerometerNoiseMPU6050 = 0.0061; % AccelerometerNoise(variance value)in units of m/s^2
            app.FUSE = imufilter('SampleRate', fs, 'GyroscopeNoise',GyroscopeNoiseMPU6050,'AccelerometerNoise', AccelerometerNoiseMPU6050);
            
            app.accelX = animatedline(app.axesAccel,"Color","r");
            app.accelY = animatedline(app.axesAccel,"Color","g");
            app.accelZ = animatedline(app.axesAccel,"Color","b");
            app.gyroX = animatedline(app.axesGyro,"Color","r");
            app.gyroY = animatedline(app.axesGyro,"Color","g");
            app.gyroZ = animatedline(app.axesGyro,"Color","b");
            varTypes = {'double','double','double','double'};
            varNames = {'Time','X','Y','Z'};
            app.accelLog = table('Size',[0, 4],'VariableTypes',varTypes,'VariableNames',varNames);
            app.gyroLog = table('Size',[0, 4],'VariableTypes',varTypes,'VariableNames',varNames);
            app.myTimer = tic;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            try
                app.StartButton.Enable = "off";
                app.DropDown.Enable = "off";
                app.a = arduino(app.port, 'ESP32-WROOM-DevKitV1', 'Libraries', 'I2C');
                app.imu = mpu6050(app.a, "OutputFormat", "matrix");
                fs = 100; % Sample Rate in Hz
    
                % GyroscopeNoise and AccelerometerNoise is determined from datasheet.
                GyroscopeNoiseMPU6050 = 3.0462e-06; % GyroscopeNoise (variance value) in units of rad/s
                AccelerometerNoiseMPU6050 = 0.0061; % AccelerometerNoise(variance value)in units of m/s^2
                app.FUSE = imufilter('SampleRate', fs, 'GyroscopeNoise',GyroscopeNoiseMPU6050,'AccelerometerNoise', AccelerometerNoiseMPU6050);
                app.viewer = HelperOrientationViewer('Title',{'Orientation'});
                
                app.accelX = animatedline(app.axesAccel,"Color","r");
                app.accelY = animatedline(app.axesAccel,"Color","g");
                app.accelZ = animatedline(app.axesAccel,"Color","b");
                app.gyroX = animatedline(app.axesGyro,"Color","r");
                app.gyroY = animatedline(app.axesGyro,"Color","g");
                app.gyroZ = animatedline(app.axesGyro,"Color","b");
                varTypes = {'double','double','double','double'};
                varNames = {'Time','X','Y','Z'};
                app.accelLog = table('Size',[0, 4],'VariableTypes',varTypes,'VariableNames',varNames);
                app.gyroLog = table('Size',[0, 4],'VariableTypes',varTypes,'VariableNames',varNames);
                app.myTimer = tic;
                app.sensorLoop = timer('TimerFcn', @(~,~) readSensor(app), 'Period', 0.001, 'TasksToExecute', Inf, 'ExecutionMode', 'fixedSpacing');
                app.viewerLoop = timer('TimerFcn', @(~,~) showViewer(app), 'Period', 0.001, 'TasksToExecute', Inf, 'ExecutionMode', 'fixedSpacing');
                start(app.sensorLoop);
                start(app.viewerLoop);
            catch
                msgbox(["Error trying to connect to Arduino.", "Please select the correct port and try again."],"Error");
                app.StartButton.Enable = "on";
                app.DropDown.Enable = "on";
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            app.port = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.axesGyro = uiaxes(app.GridLayout3);
            title(app.axesGyro, 'Raw Gyroscope')
            xlabel(app.axesGyro, 't (s)')
            ylabel(app.axesGyro, 'ω (rad/s)')
            zlabel(app.axesGyro, 'Z')
            app.axesGyro.XLim = [0 20];
            app.axesGyro.YLim = [-5 5];
            app.axesGyro.YTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.axesGyro.YTickLabel = {'-5'; '-4'; '-3'; '-2'; '-1'; '0'; '1'; '2'; '3'; '4'; '5'};
            app.axesAccel = uiaxes(app.GridLayout3);
            title(app.axesAccel, 'Raw Accelerometer')
            xlabel(app.axesAccel, 't (s)')
            ylabel(app.axesAccel, 'a (m/s^2)')
            zlabel(app.axesAccel, 'Z')
            app.axesAccel.XLim = [0 20];
            app.axesAccel.YLim = [-10 10];
            app.axesAccel.YTick = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.axesAccel.YTickLabel = {'-10'; '-8'; '-6'; '-4'; '-2'; '0'; '2'; '4'; '6'; '8'; '10'};
            app.Panel_2 = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel_2);
            app.OpenOrientationViewerButton = uibutton(app.GridLayout2, 'push');
            app.OpenOrientationViewerButton.ButtonPushedFcn = createCallbackFcn(app, @OpenOrientationViewerButtonPushed, true);
            app.OpenOrientationViewerButton.Text = {'Open '; 'Orientation Viewer'};
            app.SavetoCSVButton = uibutton(app.GridLayout2, 'push');
            app.SavetoCSVButton.ButtonPushedFcn = createCallbackFcn(app, @SavetoCSVButtonPushed, true);
            app.SavetoCSVButton.Text = 'Save to CSV';
            app.ResetButton = uibutton(app.GridLayout2, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.StartButton = uibutton(app.GridLayout2, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.DropDown = uidropdown(app.GridLayout2);
            app.DropDown.Items = {};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end